classdef PedestrianDetectionAPP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MultiCameraPedestrianDetectionBenchmarkUIFigure  matlab.ui.Figure
        FileMenu                 matlab.ui.container.Menu
        OpenMenu                 matlab.ui.container.Menu
        OpenprecomputedevaluationfileMenu  matlab.ui.container.Menu
        CloseMenu                matlab.ui.container.Menu
        HelpMenu                 matlab.ui.container.Menu
        AppInformationMenu       matlab.ui.container.Menu
        HelpMenu_2               matlab.ui.container.Menu
        UIAxesCam1               matlab.ui.control.UIAxes
        UIAxesCam2               matlab.ui.control.UIAxes
        UIAxesCam3               matlab.ui.control.UIAxes
        UIAxesCenital            matlab.ui.control.UIAxes
        UITableResults           matlab.ui.control.Table
        FrameSelectionPanel      matlab.ui.container.Panel
        ActualFrame              matlab.ui.control.Label
        Label2                   matlab.ui.control.Label
        TotalFrames              matlab.ui.control.Label
        ButtonBack               matlab.ui.control.Button
        ButtonNext               matlab.ui.control.Button
        CheckBoxGT               matlab.ui.control.CheckBox
        GotoframeLabel           matlab.ui.control.Label
        GoToFrame                matlab.ui.control.NumericEditField
        DetectorsLabel           matlab.ui.control.Label
        DropDownDetectors        matlab.ui.control.DropDown
        PDGlobalThresholdLabel   matlab.ui.control.Label
        PDTh                     matlab.ui.control.Slider
        PDGlobalThFlag           matlab.ui.control.CheckBox
        MultiCameraPedestrianDetectionBenchmarkandVisualizationLabel  matlab.ui.control.Label
        UIAxesVPULogo            matlab.ui.control.UIAxes
        UIAxesHALogo             matlab.ui.control.UIAxes
        PDThresholdCamera1Label  matlab.ui.control.Label
        PDThCam1                 matlab.ui.control.Slider
        PDThresholdCamera2Label  matlab.ui.control.Label
        PDThCam2                 matlab.ui.control.Slider
        PDThresholdCamera3Label  matlab.ui.control.Label
        PDThCam3                 matlab.ui.control.Slider
        PDThInputCam1            matlab.ui.control.NumericEditField
        PDThInputCam3            matlab.ui.control.NumericEditField
        PDThInputCam2            matlab.ui.control.NumericEditField
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global GeneralDataPath
            
            GeneralDataPath = 'E:\HA-Video Benchmark';
            
            VPULogo = imread(fullfile('Logos', 'VPULogo.jpg'));
            HAVideo = imread(fullfile('Logos', 'HALogo.jpg'));
            imshow(VPULogo, 'Parent', app.UIAxesVPULogo);
            imshow(HAVideo, 'Parent', app.UIAxesHALogo);
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            % Declare global variables
            global BlobStructure
            global BlobStructureGT
            global counter
            global datasetFiles
            global nFrames
            global detectors
            global GeneralDataPath
            
            Filters = {'*.idl','Bounding Boxes Files (*.idl)'; '*.*',  'All Files (*.*)'};
            [file,path,indx] = uigetfile(Filters, 'Select one or more files', 'MultiSelect','on');
            
            counter = 1;
            
            for i = 1: 3
                datasetFiles{1,i} = dir(fullfile(GeneralDataPath,  'Dataset', ['Camera ' num2str(i)], '*.jpg'));
                nFrames(i) = numel(datasetFiles{1,i});
            end
            
            nFrames = max(nFrames) - 3;
            
            app.TotalFrames.Text = num2str(nFrames);
            app.ActualFrame.Text = num2str(counter);
            
            [BlobStructure, BlobStructureGT, detectors] = processFiles(app, file, counter, datasetFiles, GeneralDataPath);
        end

        % Menu selected function: OpenprecomputedevaluationfileMenu
        function OpenprecomputedevaluationfileMenuSelected(app, event)
            % Declare global variables
            global BlobStructure
            global BlobStructureGT
            global counter
            global datasetFiles
            global nFrames
            global detectors
            global GeneralDataPath
            
            clc;
            Filters = {'*.mat','Evalutation File (*.mat)'; '*.*',  'All Files (*.*)'};
            [file,path,indx] = uigetfile(Filters, 'Select one evaluation file');
            
            counter = 1;
            
            for i = 1: 3
                datasetFiles{1,i} = dir(fullfile(GeneralDataPath,  'Dataset', ['Camera ' num2str(i)], '*.jpg'));
                nFrames(i) = numel(datasetFiles{1,i});
            end
            
            nFrames = max(nFrames) - 3;
            
            app.TotalFrames.Text = num2str(nFrames);
            app.ActualFrame.Text = num2str(counter);
            
            [BlobStructure, BlobStructureGT, detectors] = processEvaluationFile(app, file, counter, datasetFiles, GeneralDataPath);
        end

        % Button pushed function: ButtonBack
        function ButtonBackPushed(app, event)
            global BlobStructure
            global BlobStructureGT
            global counter
            global datasetFiles
            global detectors
            global GeneralDataPath
            
            if(counter > 1)
                counter = counter - 1;
                
                CenitalPlane = imread(fullfile(GeneralDataPath, 'Dataset', 'RGBComplete.png'));
                
                for i = 1 : 3
                    CenitalPlane = displayFrame(i, counter, datasetFiles{1,i}(counter), app, detectors, BlobStructure, BlobStructureGT, GeneralDataPath, CenitalPlane);
                end
                imshow(CenitalPlane, 'Parent', app.UIAxesCenital);
                
                app.ActualFrame.Text = num2str(counter);
            end
        end

        % Button pushed function: ButtonNext
        function ButtonNextPushed(app, event)
            global BlobStructure
            global BlobStructureGT
            global counter
            global datasetFiles
            global nFrames
            global detectors
            global GeneralDataPath
            
            if(counter <= nFrames)
                counter = counter + 1;
                
                CenitalPlane = imread(fullfile(GeneralDataPath, 'Dataset', 'RGBComplete.png'));
                
                for i = 1 : 3
                    CenitalPlane = displayFrame(i, counter, datasetFiles{1,i}(counter), app, detectors, BlobStructure, BlobStructureGT, GeneralDataPath, CenitalPlane);
                end
                imshow(CenitalPlane, 'Parent', app.UIAxesCenital);
                app.ActualFrame.Text = num2str(counter);
            end
        end

        % Value changed function: GoToFrame
        function GoToFrameValueChanged(app, event)
            global BlobStructure
            global BlobStructureGT
            global counter
            global datasetFiles
            global detectors
            global nFrames
            global GeneralDataPath
            
            if(counter >= 1 && counter <= nFrames)
                counter = app.GoToFrame.Value;
                
                CenitalPlane = imread(fullfile(GeneralDataPath, 'Dataset', 'RGBComplete.png'));
                
                for i = 1 : 3
                    CenitalPlane = displayFrame(i, counter, datasetFiles{1,i}(counter), app, detectors, BlobStructure, BlobStructureGT, GeneralDataPath, CenitalPlane);
                end
                imshow(CenitalPlane, 'Parent', app.UIAxesCenital);
                app.ActualFrame.Text = num2str(counter);
            end
        end

        % Value changed function: CheckBoxGT
        function CheckBoxGTValueChanged(app, event)
            global BlobStructure
            global BlobStructureGT
            global counter
            global datasetFiles
            global detectors
            global GeneralDataPath
            
            CenitalPlane = imread(fullfile(GeneralDataPath, 'Dataset', 'RGBComplete.png'));
            
            for i = 1 : 3
                CenitalPlane = displayFrame(i, counter, datasetFiles{1,i}(counter), app, detectors, BlobStructure, BlobStructureGT, GeneralDataPath, CenitalPlane);
            end
            imshow(CenitalPlane, 'Parent', app.UIAxesCenital);
        end

        % Value changed function: DropDownDetectors
        function DropDownDetectorsValueChanged(app, event)
            global BlobStructure
            global BlobStructureGT
            global counter
            global datasetFiles
            global detectors
            global GeneralDataPath
            
            CenitalPlane = imread(fullfile(GeneralDataPath, 'Dataset', 'RGBComplete.png'));
            
            for i = 1 : 3
                CenitalPlane = displayFrame(i, counter, datasetFiles{1,i}(counter), app, detectors, BlobStructure, BlobStructureGT, GeneralDataPath, CenitalPlane);
            end
            imshow(CenitalPlane, 'Parent', app.UIAxesCenital);
        end

        % Value changed function: PDTh
        function PDThValueChanged(app, event)
            global BlobStructure
            global BlobStructureGT
            global counter
            global datasetFiles
            global detectors
            global GeneralDataPath
            
            CenitalPlane = imread(fullfile(GeneralDataPath, 'Dataset', 'RGBComplete.png'));
            
            for i = 1 : 3
                CenitalPlane = displayFrame(i, counter, datasetFiles{1,i}(counter), app, detectors, BlobStructure, BlobStructureGT, GeneralDataPath, CenitalPlane);
            end
            imshow(CenitalPlane, 'Parent', app.UIAxesCenital);
        end

        % Menu selected function: CloseMenu
        function CloseMenuSelected(app, event)
            % Delete UIFigure when app is deleted
            delete(app.MultiCameraPedestrianDetectionBenchmarkUIFigure)
        end

        % Value changed function: PDThCam1
        function PDThCam1ValueChanged(app, event)
            global BlobStructure
            global BlobStructureGT
            global counter
            global datasetFiles
            global detectors
            global GeneralDataPath
            
            CenitalPlane = imread(fullfile(GeneralDataPath, 'Dataset', 'RGBComplete.png'));
            
            for i = 1 : 3
                CenitalPlane = displayFrame(i, counter, datasetFiles{1,i}(counter), app, detectors, BlobStructure, BlobStructureGT, GeneralDataPath, CenitalPlane);
            end
            imshow(CenitalPlane, 'Parent', app.UIAxesCenital);
        end

        % Value changed function: PDThCam2
        function PDThCam2ValueChanged(app, event)
            global BlobStructure
            global BlobStructureGT
            global counter
            global datasetFiles
            global detectors
            global GeneralDataPath
            
            CenitalPlane = imread(fullfile(GeneralDataPath, 'Dataset', 'RGBComplete.png'));
            
            for i = 1 : 3
                CenitalPlane = displayFrame(i, counter, datasetFiles{1,i}(counter), app, detectors, BlobStructure, BlobStructureGT, GeneralDataPath, CenitalPlane);
            end
            imshow(CenitalPlane, 'Parent', app.UIAxesCenital);
        end

        % Value changed function: PDThCam3
        function PDThCam3ValueChanged(app, event)
            global BlobStructure
            global BlobStructureGT
            global counter
            global datasetFiles
            global detectors
            global GeneralDataPath
            
            CenitalPlane = imread(fullfile(GeneralDataPath, 'Dataset', 'RGBComplete.png'));
            
            for i = 1 : 3
                CenitalPlane = displayFrame(i, counter, datasetFiles{1,i}(counter), app, detectors, BlobStructure, BlobStructureGT, GeneralDataPath, CenitalPlane);
            end
            imshow(CenitalPlane, 'Parent', app.UIAxesCenital);
        end

        % Value changed function: PDThInputCam1
        function PDThInputCam1ValueChanged(app, event)
            app.PDThCam1.Value = app.PDThInputCam1.Value;
            
            PDThCam1ValueChanged(app, event)
        end

        % Value changed function: PDThInputCam3
        function PDThInputCam3ValueChanged(app, event)
            app.PDThCam3.Value = app.PDThInputCam3.Value;
            
            PDThCam1ValueChanged(app, event)
        end

        % Value changed function: PDThInputCam2
        function PDThInputCam2ValueChanged(app, event)
            app.PDThCam2.Value = app.PDThInputCam2.Value;
            
            PDThCam1ValueChanged(app, event)
        end

        % Value changed function: PDGlobalThFlag
        function PDGlobalThFlagValueChanged(app, event)
            value = app.PDGlobalThFlag.Value;
            
            if(value)
                PDThValueChanged(app, event)
            else
                PDThCam1ValueChanged(app, event)
                PDThCam2ValueChanged(app, event)
                PDThCam3ValueChanged(app, event)
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MultiCameraPedestrianDetectionBenchmarkUIFigure = uifigure;
            app.MultiCameraPedestrianDetectionBenchmarkUIFigure.Name = 'Multi-Camera Pedestrian Detection Benchmark';
            app.FileMenu = uimenu(app.MultiCameraPedestrianDetectionBenchmarkUIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open...';
            app.OpenprecomputedevaluationfileMenu = uimenu(app.FileMenu);
            app.OpenprecomputedevaluationfileMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenprecomputedevaluationfileMenuSelected, true);
            app.OpenprecomputedevaluationfileMenu.Text = 'Open precomputed evaluation file...';
            app.CloseMenu = uimenu(app.FileMenu);
            app.CloseMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseMenuSelected, true);
            app.CloseMenu.Text = 'Close';
            app.HelpMenu = uimenu(app.MultiCameraPedestrianDetectionBenchmarkUIFigure);
            app.HelpMenu.Text = 'Help';
            app.AppInformationMenu = uimenu(app.HelpMenu);
            app.AppInformationMenu.Text = 'App Information';
            app.HelpMenu_2 = uimenu(app.HelpMenu);
            app.HelpMenu_2.Text = 'Help';
            app.UIAxesCam1 = uiaxes(app.MultiCameraPedestrianDetectionBenchmarkUIFigure);
            title(app.UIAxesCam1, 'Camera 1')
            app.UIAxesCam1.XTick = [];
            app.UIAxesCam1.XTickLabel = '';
            app.UIAxesCam1.YTick = [];
            app.UIAxesCam1.YTickLabel = '';
            app.UIAxesCam2 = uiaxes(app.MultiCameraPedestrianDetectionBenchmarkUIFigure);
            title(app.UIAxesCam2, 'Camera 2')
            app.UIAxesCam2.XTick = [];
            app.UIAxesCam2.XTickLabel = '';
            app.UIAxesCam2.YTick = [];
            app.UIAxesCam2.YTickLabel = '';
            app.UIAxesCam3 = uiaxes(app.MultiCameraPedestrianDetectionBenchmarkUIFigure);
            title(app.UIAxesCam3, 'Camera 3')
            app.UIAxesCam3.XTick = [];
            app.UIAxesCam3.XTickLabel = '';
            app.UIAxesCam3.YTick = [];
            app.UIAxesCam3.YTickLabel = '';
            app.UIAxesCenital = uiaxes(app.MultiCameraPedestrianDetectionBenchmarkUIFigure);
            title(app.UIAxesCenital, 'Cenital Plane')
            app.UIAxesCenital.XTick = [];
            app.UIAxesCenital.XTickLabel = '';
            app.UIAxesCenital.YTick = [];
            app.UIAxesCenital.YTickLabel = '';
            app.UITableResults = uitable(app.MultiCameraPedestrianDetectionBenchmarkUIFigure);
            app.UITableResults.ColumnName = {'Precision'; 'Recall'; 'F-Score'; 'AUC'; 'N-MODA'; 'N-MODP'};
            app.UITableResults.RowName = {};
            app.FrameSelectionPanel = uipanel(app.MultiCameraPedestrianDetectionBenchmarkUIFigure);
            app.FrameSelectionPanel.Title = 'Frame Selection';
            app.ActualFrame = uilabel(app.FrameSelectionPanel);
            app.ActualFrame.Text = '0';
            app.Label2 = uilabel(app.FrameSelectionPanel);
            app.Label2.Text = '/';
            app.TotalFrames = uilabel(app.FrameSelectionPanel);
            app.TotalFrames.Text = '0';
            app.ButtonBack = uibutton(app.FrameSelectionPanel, 'push');
            app.ButtonBack.ButtonPushedFcn = createCallbackFcn(app, @ButtonBackPushed, true);
            app.ButtonBack.Text = 'Previous';
            app.ButtonNext = uibutton(app.FrameSelectionPanel, 'push');
            app.ButtonNext.ButtonPushedFcn = createCallbackFcn(app, @ButtonNextPushed, true);
            app.ButtonNext.Text = 'Next';
            app.CheckBoxGT = uicheckbox(app.FrameSelectionPanel);
            app.CheckBoxGT.ValueChangedFcn = createCallbackFcn(app, @CheckBoxGTValueChanged, true);
            app.CheckBoxGT.Text = 'Display Ground-Truth';
            app.GotoframeLabel = uilabel(app.FrameSelectionPanel);
            app.GotoframeLabel.Text = 'Go to frame...';
            app.GoToFrame = uieditfield(app.FrameSelectionPanel, 'numeric');
            app.GoToFrame.ValueChangedFcn = createCallbackFcn(app, @GoToFrameValueChanged, true);
            app.DetectorsLabel = uilabel(app.FrameSelectionPanel);
            app.DetectorsLabel.Text = 'Detectors';
            app.DropDownDetectors = uidropdown(app.FrameSelectionPanel);
            app.DropDownDetectors.ValueChangedFcn = createCallbackFcn(app, @DropDownDetectorsValueChanged, true);
            app.PDGlobalThresholdLabel = uilabel(app.FrameSelectionPanel);
            app.PDGlobalThresholdLabel.Text = {'PD Global'; 'Threshold'};
            app.PDTh = uislider(app.FrameSelectionPanel);
            app.PDTh.Limits = [0 1];
            app.PDTh.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.PDTh.MajorTickLabels = {'0', '0.2', '0.4', '0.6', '0.8', '1'};
            app.PDTh.ValueChangedFcn = createCallbackFcn(app, @PDThValueChanged, true);
            app.PDTh.MinorTicks = [0 0.04 0.08 0.12 0.16 0.2 0.24 0.28 0.32 0.36 0.4 0.44 0.48 0.52 0.56 0.6 0.64 0.68 0.72 0.76 0.8 0.84 0.88 0.92 0.96 1];
            app.PDGlobalThFlag = uicheckbox(app.FrameSelectionPanel);
            app.PDGlobalThFlag.ValueChangedFcn = createCallbackFcn(app, @PDGlobalThFlagValueChanged, true);
            app.PDGlobalThFlag.Text = '';
            app.PDGlobalThFlag.Value = true;
            app.MultiCameraPedestrianDetectionBenchmarkandVisualizationLabel = uilabel(app.MultiCameraPedestrianDetectionBenchmarkUIFigure);
            app.MultiCameraPedestrianDetectionBenchmarkandVisualizationLabel.Text = 'Multi-Camera Pedestrian Detection Benchmark and Visualization';
            app.UIAxesVPULogo = uiaxes(app.MultiCameraPedestrianDetectionBenchmarkUIFigure);
            app.UIAxesVPULogo.XTickLabel = '';
            app.UIAxesVPULogo.YTickLabel = '';
            app.UIAxesHALogo = uiaxes(app.MultiCameraPedestrianDetectionBenchmarkUIFigure);
            app.UIAxesHALogo.XTickLabel = '';
            app.UIAxesHALogo.YTickLabel = '';
            app.PDThresholdCamera1Label = uilabel(app.MultiCameraPedestrianDetectionBenchmarkUIFigure);
            app.PDThresholdCamera1Label.Text = {'PD Threshold'; 'Camera 1'};
            app.PDThCam1 = uislider(app.MultiCameraPedestrianDetectionBenchmarkUIFigure);
            app.PDThCam1.Limits = [0 1];
            app.PDThCam1.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.PDThCam1.MajorTickLabels = {'0', '0.2', '0.4', '0.6', '0.8', '1'};
            app.PDThCam1.ValueChangedFcn = createCallbackFcn(app, @PDThCam1ValueChanged, true);
            app.PDThCam1.MinorTicks = [0 0.04 0.08 0.12 0.16 0.2 0.24 0.28 0.32 0.36 0.4 0.44 0.48 0.52 0.56 0.6 0.64 0.68 0.72 0.76 0.8 0.84 0.88 0.92 0.96 1];
            app.PDThresholdCamera2Label = uilabel(app.MultiCameraPedestrianDetectionBenchmarkUIFigure);
            app.PDThresholdCamera2Label.Text = {'PD Threshold'; 'Camera 2'};
            app.PDThCam2 = uislider(app.MultiCameraPedestrianDetectionBenchmarkUIFigure);
            app.PDThCam2.Limits = [0 1];
            app.PDThCam2.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.PDThCam2.MajorTickLabels = {'0', '0.2', '0.4', '0.6', '0.8', '1'};
            app.PDThCam2.ValueChangedFcn = createCallbackFcn(app, @PDThCam2ValueChanged, true);
            app.PDThCam2.MinorTicks = [0 0.04 0.08 0.12 0.16 0.2 0.24 0.28 0.32 0.36 0.4 0.44 0.48 0.52 0.56 0.6 0.64 0.68 0.72 0.76 0.8 0.84 0.88 0.92 0.96 1];
            app.PDThresholdCamera3Label = uilabel(app.MultiCameraPedestrianDetectionBenchmarkUIFigure);
            app.PDThresholdCamera3Label.Text = {'PD Threshold'; 'Camera 3'};
            app.PDThCam3 = uislider(app.MultiCameraPedestrianDetectionBenchmarkUIFigure);
            app.PDThCam3.Limits = [0 1];
            app.PDThCam3.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.PDThCam3.MajorTickLabels = {'0', '0.2', '0.4', '0.6', '0.8', '1'};
            app.PDThCam3.ValueChangedFcn = createCallbackFcn(app, @PDThCam3ValueChanged, true);
            app.PDThCam3.MinorTicks = [0 0.04 0.08 0.12 0.16 0.2 0.24 0.28 0.32 0.36 0.4 0.44 0.48 0.52 0.56 0.6 0.64 0.68 0.72 0.76 0.8 0.84 0.88 0.92 0.96 1];
            app.PDThInputCam1 = uieditfield(app.MultiCameraPedestrianDetectionBenchmarkUIFigure, 'numeric');
            app.PDThInputCam1.Limits = [0 1];
            app.PDThInputCam1.ValueChangedFcn = createCallbackFcn(app, @PDThInputCam1ValueChanged, true);
            app.PDThInputCam3 = uieditfield(app.MultiCameraPedestrianDetectionBenchmarkUIFigure, 'numeric');
            app.PDThInputCam3.Limits = [0 1];
            app.PDThInputCam3.ValueChangedFcn = createCallbackFcn(app, @PDThInputCam3ValueChanged, true);
            app.PDThInputCam2 = uieditfield(app.MultiCameraPedestrianDetectionBenchmarkUIFigure, 'numeric');
            app.PDThInputCam2.Limits = [0 1];
            app.PDThInputCam2.ValueChangedFcn = createCallbackFcn(app, @PDThInputCam2ValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = PedestrianDetectionAPP

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MultiCameraPedestrianDetectionBenchmarkUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MultiCameraPedestrianDetectionBenchmarkUIFigure)
        end
    end
end